classdef projectno2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        EditField_4  matlab.ui.control.EditField
        EditField_3  matlab.ui.control.NumericEditField
        EditField_2  matlab.ui.control.NumericEditField
        EditField    matlab.ui.control.NumericEditField
        RUNButton    matlab.ui.control.Button
        Image        matlab.ui.control.Image
    end

    
    properties (Access = private)
        x; % Description
readfile;
result;
pro;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.readfile=readfis('Logic.fis');
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
     
       app.result=evalfis(app.readfile,[app.EditField.Value app.EditField_2.Value]);
       if(app.result <=5)
           app.pro=1;
           app.EditField_4.Value="Bad";
       elseif(app.result >5 &&app.result <=10)
           app.pro=2;
           app.EditField_4.Value="Good";
       else 
          app.pro=3;
          app.EditField_4.Value="Best";
       end
     app.EditField_3.Value=app.pro;
     
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'FuzzyLogicGUI.png');
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [0 10];
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [0 10];
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Limits = [0 15];
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projectno2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end